classdef adohe_fototoko_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        OpenMenu                        matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup2                       matlab.ui.container.TabGroup
        ImageTab                        matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        InputImage                      matlab.ui.control.Image
        OutputImage                     matlab.ui.control.Image
        SpectrumTab                     matlab.ui.container.Tab
        GridLayout17                    matlab.ui.container.GridLayout
        OutputSpectrum                  matlab.ui.control.Image
        OutputLabel_2                   matlab.ui.control.Label
        InputLabel_2                    matlab.ui.control.Label
        InputSpectrum                   matlab.ui.control.Image
        HistogramTab_2                  matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        OutputLabel                     matlab.ui.control.Label
        InputLabel                      matlab.ui.control.Label
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes5                         matlab.ui.control.UIAxes
        UIAxes6                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        TabGroup                        matlab.ui.container.TabGroup
        OpenFileTab                     matlab.ui.container.Tab
        GridLayout6_2                   matlab.ui.container.GridLayout
        ChooseImageButton               matlab.ui.control.Button
        NoFileSelectedLabel             matlab.ui.control.Label
        HistogramTab                    matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        ProcessButton_3                 matlab.ui.control.Button
        KonvolusiTab                    matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        x7GaussianMaskLabel             matlab.ui.control.Label
        x33Label                        matlab.ui.control.Label
        x32Label                        matlab.ui.control.Label
        x31Label                        matlab.ui.control.Label
        Image4                          matlab.ui.control.Image
        Image3                          matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        ChooseMaskButtonGroup           matlab.ui.container.ButtonGroup
        x7GaussianMaskButton            matlab.ui.control.RadioButton
        x33Button                       matlab.ui.control.RadioButton
        x32Button                       matlab.ui.control.RadioButton
        x31Button                       matlab.ui.control.RadioButton
        ApplyButton                     matlab.ui.control.Button
        ProcessButton_4                 matlab.ui.control.Button
        SmoothingTab                    matlab.ui.container.Tab
        GridLayout7_2                   matlab.ui.container.GridLayout
        SmoothingD0                     matlab.ui.control.NumericEditField
        D0radiusforILPFGLPFandBLPFEditFieldLabel  matlab.ui.control.Label
        SmoothingMaskSize               matlab.ui.control.NumericEditField
        nmasksizeformeanandgaussianfilteringLabel  matlab.ui.control.Label
        SmoothingMethods                matlab.ui.container.ButtonGroup
        BLPFButton                      matlab.ui.control.RadioButton
        GLPFButton                      matlab.ui.control.RadioButton
        ILPFButton                      matlab.ui.control.RadioButton
        GaussianfilterButton            matlab.ui.control.RadioButton
        MeanfilterButton                matlab.ui.control.RadioButton
        ApplyButton_2                   matlab.ui.control.Button
        ProcessButton_5                 matlab.ui.control.Button
        HighpassTab                     matlab.ui.container.Tab
        GridLayout7_3                   matlab.ui.container.GridLayout
        HighpassD0                      matlab.ui.control.NumericEditField
        D0Label                         matlab.ui.control.Label
        HighpassMethod                  matlab.ui.container.ButtonGroup
        BHPFButton                      matlab.ui.control.RadioButton
        GHPFButton                      matlab.ui.control.RadioButton
        IHPFButton                      matlab.ui.control.RadioButton
        ApplyButton_3                   matlab.ui.control.Button
        ProcessButton_6                 matlab.ui.control.Button
        DerauTab                        matlab.ui.container.Tab
        GridLayout8                     matlab.ui.container.GridLayout
        GridLayout12                    matlab.ui.container.GridLayout
        dVal                            matlab.ui.control.NumericEditField
        alphaLabel                      matlab.ui.control.Label
        GridLayout11                    matlab.ui.container.GridLayout
        qVal                            matlab.ui.control.NumericEditField
        QEditFieldLabel                 matlab.ui.control.Label
        GridLayout10                    matlab.ui.container.GridLayout
        ukuranFilter                    matlab.ui.control.NumericEditField
        filterNxNLabel                  matlab.ui.control.Label
        ApplySaltButton                 matlab.ui.control.Button
        GridLayout9                     matlab.ui.container.GridLayout
        density                         matlab.ui.control.NumericEditField
        densityEditFieldLabel           matlab.ui.control.Label
        AlphatrimmedMeanFilterButton    matlab.ui.control.Button
        MidpointFilterButton            matlab.ui.control.Button
        ContraharmonicMeanFilterButton  matlab.ui.control.Button
        HarmonicMeanFilterButton        matlab.ui.control.Button
        GeometricFilterButton           matlab.ui.control.Button
        ArithmeticMeanFilterButton      matlab.ui.control.Button
        MedianFilterButton              matlab.ui.control.Button
        MaxFilterButton                 matlab.ui.control.Button
        MinFilterButton                 matlab.ui.control.Button
        AddSaltPaperButton              matlab.ui.control.Button
        PeriodicTab                     matlab.ui.container.Tab
        GridLayout18                    matlab.ui.container.GridLayout
        NoisyimagesamplesareavailableinthetestfolderLabel  matlab.ui.control.Label
        AutomaticallydenoiseperiodicnoiseLabel  matlab.ui.control.Label
        ApplyButton_4                   matlab.ui.control.Button
        ProcessButton_7                 matlab.ui.control.Button
        MotionblurTab                   matlab.ui.container.Tab
        GridLayout13                    matlab.ui.container.GridLayout
        misal0001Label                  matlab.ui.control.Label
        GridLayout16                    matlab.ui.container.GridLayout
        NoiseVar                        matlab.ui.control.NumericEditField
        NoiseVarEditFieldLabel          matlab.ui.control.Label
        PenapisWeinerButton             matlab.ui.control.Button
        ApplyMotionBlurButton           matlab.ui.control.Button
        GridLayout15                    matlab.ui.container.GridLayout
        degree                          matlab.ui.control.NumericEditField
        degreeEditFieldLabel            matlab.ui.control.Label
        GridLayout14                    matlab.ui.container.GridLayout
        len                             matlab.ui.control.NumericEditField
        lenEditFieldLabel               matlab.ui.control.Label
        AddMotionBlurButton             matlab.ui.control.Button
        CitraTerangTab                  matlab.ui.container.Tab
        GridLayout19                    matlab.ui.container.GridLayout
        NilaiX                          matlab.ui.control.NumericEditField
        NilaiXEditFieldLabel            matlab.ui.control.Label
        ProccessButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        Fullimagename % Full image name
        Inputimagedata % Image data (input)
        Outputimagedata % Image data (output)
        SpecTargetImagedata % Description
        SpecTargetFullImagename % Description
    end
    
    methods (Access = private)
        
        function setHistogram(~, imagedata, UIImage, RAxes, GAxes, BAxes)
            % clear histogram
            cla(RAxes);
            cla(GAxes);
            cla(BAxes);
            title(RAxes, '');
            title(GAxes, '');
            title(BAxes, '');

            % handle rgb image
            numChannel = size(imagedata, 3);
            if numChannel == 3
                % Display the image in the ui image component
                UIImage.ImageSource = imagedata;
            
                % Separate the RGB channels
                redChannel = imagedata(:, :, 1);
                greenChannel = imagedata(:, :, 2);
                blueChannel = imagedata(:, :, 3);
                
                % create histogram matrix
                redHistogram = zeros(256, 1);
                greenHistogram = zeros(256, 1);
                blueHistogram = zeros(256, 1);             

                % Calculate histograms for each channel
                [height, width] = size(redChannel);
                
                % calculate r,g, and b value
                for i = 1:height
                    for j = 1:width
                        redValue = redChannel(i, j) + 1; % Add 1 to avoid indexing issues
                        greenValue = greenChannel(i, j) + 1;
                        blueValue = blueChannel(i, j) + 1;
                        
                        redHistogram(redValue) = redHistogram(redValue) + 1;
                        greenHistogram(greenValue) = greenHistogram(greenValue) + 1;
                        blueHistogram(blueValue) = blueHistogram(blueValue) + 1;
                    end
                end
                
                % Plot histograms
                bar(RAxes, 0:255, redHistogram, 'r'); % Red channel histogram
                hold(RAxes, 'on');
                bar(GAxes, 0:255, greenHistogram, 'g'); % Green channel histogram
                hold(GAxes, 'on');
                bar(BAxes, 0:255, blueHistogram, 'b'); % Blue channel histogram
                hold(BAxes, 'on');
                
                % Set titles for each axes
                title(RAxes, 'Red Channel Histogram');
                title(GAxes, 'Green Channel Histogram');
                title(BAxes, 'Blue Channel Histogram');
                
                % Set the limit to the maximum value
                ylim(RAxes, [0, max(redHistogram)]); 
                ylim(GAxes, [0, max(greenHistogram)]);
                ylim(BAxes, [0, max(blueHistogram)]); 

                % limit x to 0-255
                xlim(RAxes, [0, 256]);
                xlim(GAxes, [0, 256]);
                xlim(BAxes, [0, 256]);

                % Turn off hold for all axes
                hold(RAxes, 'off');
                hold(GAxes, 'off');
                hold(BAxes, 'off');
           
           % handle grayscale image
           elseif numChannel == 1
                % set to show image for greyscale
                rgbimage = cat(3, imagedata(:, :, 1),imagedata(:, :, 1),imagedata(:, :, 1));
                % Display the image in the uiimage component
                UIImage.ImageSource = rgbimage;
                
                % grey channel
                greyChannel = imagedata(:, :, 1);
                
                % grey histogram
                greyHistogram = zeros(256, 1);

                % Calculate histograms for each channel
                [height, width] = size(greyChannel);
                
                % calculate grey value
                for i = 1:height
                    for j = 1:width
                        greyValue = greyChannel(i, j) + 1; % Add 1 to avoid indexing issues
                        
                        greyHistogram(greyValue) = greyHistogram(greyValue) + 1;
                    end
                end
                
                % plot histograms
                bar(RAxes,0:255, greyHistogram, 'k'); % Grey channel histogram
                hold(RAxes, 'on');
                
                % Set the limit to the maximum value
                ylim(RAxes, [0, max(greyHistogram)]); 

                % limit x to 0-255
                xlim(RAxes, [0, 256]);
                
                % Set a title for the axis
                title(RAxes, 'Grayscale Image Histogram');
                hold(RAxes, 'off');
           else
                % Handle other cases (e.g., images with more than 3 channels)
                msgbox('Unsupported image format', 'Error', 'error');
           end
        end

        function setHistogramInput(app, numChannel)
            
            % clear histogram
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            title(app.UIAxes, '');
            title(app.UIAxes2, '');
            title(app.UIAxes3, '');

            % handle rgb image
            if numChannel == 3
                % Display the image in the ui image component
                app.InputImage.ImageSource = app.Inputimagedata;

                % Display combined spectrum
                F = fftshift(fft2(double(app.Inputimagedata)));
                Ft = log(abs(F) + 1);
                Ft = sum(Ft, 3);
                Ft = Ft - min(Ft(:));
                Ft = Ft ./ max(Ft(:));
                
                app.InputSpectrum.ImageSource = cat(3, Ft, Ft, Ft);
            
                % Separate the RGB channels
                redChannel = app.Inputimagedata(:, :, 1);
                greenChannel = app.Inputimagedata(:, :, 2);
                blueChannel = app.Inputimagedata(:, :, 3);
                
                % create histogram matrix
                redHistogram = zeros(256, 1);
                greenHistogram = zeros(256, 1);
                blueHistogram = zeros(256, 1);             

                % Calculate histograms for each channel
                [height, width] = size(redChannel);
                
                % calculate r,g, and b value
                for i = 1:height
                    for j = 1:width
                        redValue = redChannel(i, j) + 1; % Add 1 to avoid indexing issues
                        greenValue = greenChannel(i, j) + 1;
                        blueValue = blueChannel(i, j) + 1;
                        
                        redHistogram(redValue) = redHistogram(redValue) + 1;
                        greenHistogram(greenValue) = greenHistogram(greenValue) + 1;
                        blueHistogram(blueValue) = blueHistogram(blueValue) + 1;
                    end
                end
                
                % Plot histograms
                bar(app.UIAxes, 0:255, redHistogram, 'r'); % Red channel histogram
                hold(app.UIAxes, 'on');
                bar(app.UIAxes2, 0:255, greenHistogram, 'g'); % Green channel histogram
                hold(app.UIAxes2, 'on');
                bar(app.UIAxes3, 0:255, blueHistogram, 'b'); % Blue channel histogram
                hold(app.UIAxes3, 'on');
                
                % Set titles for each axes
                title(app.UIAxes, 'Red Channel Histogram');
                title(app.UIAxes2, 'Green Channel Histogram');
                title(app.UIAxes3, 'Blue Channel Histogram');
                
                % Set the limit to the maximum value
                ylim(app.UIAxes, [0, max(redHistogram)]); 
                ylim(app.UIAxes2, [0, max(greenHistogram)]);
                ylim(app.UIAxes3, [0, max(blueHistogram)]); 

                % limit x to 0-255
                xlim(app.UIAxes, [0, 256]);
                xlim(app.UIAxes2, [0, 256]);
                xlim(app.UIAxes3, [0, 256]);

                % Turn off hold for all axes
                hold(app.UIAxes, 'off');
                hold(app.UIAxes2, 'off');
                hold(app.UIAxes3, 'off');
           
           % handle grayscale image
           elseif numChannel == 1
                % set to show image for greyscale
                rgbimage = cat(3, app.Inputimagedata(:, :, 1),app.Inputimagedata(:, :, 1),app.Inputimagedata(:, :, 1));
                % Display the image in the uiimage component
                app.InputImage.ImageSource = rgbimage;

                % Display spectrum
                F = fftshift(fft2(double(app.Inputimagedata)));
                Ft = log(abs(F) + 1);
                Ft = Ft - min(Ft(:));
                Ft = Ft ./ max(Ft(:));
                
                app.InputSpectrum.ImageSource = cat(3, Ft, Ft, Ft);
                
                % grey channel
                greyChannel = app.Inputimagedata(:, :, 1);
                
                % grey histogram
                greyHistogram = zeros(256, 1);

                % Calculate histograms for each channel
                [height, width] = size(greyChannel);
                
                % calculate grey value
                for i = 1:height
                    for j = 1:width
                        greyValue = greyChannel(i, j) + 1; % Add 1 to avoid indexing issues
                        
                        greyHistogram(greyValue) = greyHistogram(greyValue) + 1;
                    end
                end
                
                % plot histograms
                bar(app.UIAxes,0:255, greyHistogram, 'k'); % Grey channel histogram
                hold(app.UIAxes, 'on');
                
                % Set the limit to the maximum value
                ylim(app.UIAxes, [0, max(greyHistogram)]); 

                % limit x to 0-255
                xlim(app.UIAxes, [0, 256]);
                
                % Set a title for the axis
                title(app.UIAxes, 'Grayscale Image Histogram');
                hold(app.UIAxes, 'off');
           else
                % Handle other cases (e.g., images with more than 3 channels)
                msgbox('Unsupported image format', 'Error', 'error');
           end
        end
        
        function setHistogramOutput(app, numChannel)
            % clear histogram
            cla(app.UIAxes4);
            cla(app.UIAxes5);
            cla(app.UIAxes6);
            title(app.UIAxes4, '');
            title(app.UIAxes5, '');
            title(app.UIAxes6, '');
            
            % handle rgb image
            if numChannel == 3
                % Display the image in the uiimage component
                app.OutputImage.ImageSource = app.Outputimagedata;

                % Display combined spectrum
                F = fftshift(fft2(double(app.Outputimagedata)));
                Ft = log(abs(F) + 1);
                Ft = sum(Ft, 3);
                Ft = Ft - min(Ft(:));
                Ft = Ft ./ max(Ft(:));
                
                app.OutputSpectrum.ImageSource = cat(3, Ft, Ft, Ft);
            
                % Separate the RGB channels
                redChannel = app.Outputimagedata(:, :, 1);
                greenChannel = app.Outputimagedata(:, :, 2);
                blueChannel = app.Outputimagedata(:, :, 3);
        
                % set value histogram
                redHistogram = zeros(256, 1);
                greenHistogram = zeros(256, 1);
                blueHistogram = zeros(256, 1);
                
                % calculate histograms for each channel
                [height, width] = size(redChannel);
                
                % calculate histogram
                for i = 1:height
                    for j = 1:width
                        redValue = redChannel(i, j) + 1; % Add 1 to avoid indexing issues
                        greenValue = greenChannel(i, j) + 1;
                        blueValue = blueChannel(i, j) + 1;
                        
                        redHistogram(redValue) = redHistogram(redValue) + 1;
                        greenHistogram(greenValue) = greenHistogram(greenValue) + 1;
                        blueHistogram(blueValue) = blueHistogram(blueValue) + 1;
                    end
                end
                
                % Plot histograms
                bar(app.UIAxes4, 0:255, redHistogram, 'r'); % Red channel histogram
                hold(app.UIAxes4, 'on');
                bar(app.UIAxes5, 0:255, greenHistogram, 'g'); % Green channel histogram
                hold(app.UIAxes5, 'on');
                bar(app.UIAxes6, 0:255, blueHistogram, 'b'); % Blue channel histogram
                hold(app.UIAxes6, 'on');
    
                % Set titles for each axes
                title(app.UIAxes4, 'Red Channel Histogram');
                title(app.UIAxes5, 'Green Channel Histogram');
                title(app.UIAxes6, 'Blue Channel Histogram');
                
                % Set the limit to the maximum value
                ylim(app.UIAxes4, [0, max(redHistogram)]); 
                ylim(app.UIAxes5, [0, max(greenHistogram)]);
                ylim(app.UIAxes6, [0, max(blueHistogram)]); 

                % limit x to 0-255
                xlim(app.UIAxes4, [0, 256]);
                xlim(app.UIAxes5, [0, 256]);
                xlim(app.UIAxes6, [0, 256]);

                % Turn off hold for all axes
                hold(app.UIAxes4, 'off');
                hold(app.UIAxes5, 'off');
                hold(app.UIAxes6, 'off');

            % handle grayscale image
            elseif numChannel == 1
                % convert grayscale to format rgb to show image
                rgbimage = cat(3, app.Outputimagedata,app.Outputimagedata,app.Outputimagedata);

                % Display the image in the uiimage component
                app.OutputImage.ImageSource = rgbimage;

                % Display spectrum
                F = fftshift(fft2(double(app.Outputimagedata)));
                Ft = log(abs(F) + 1);
                Ft = Ft - min(Ft(:));
                Ft = Ft ./ max(Ft(:));
                
                app.OutputSpectrum.ImageSource = cat(3, Ft, Ft, Ft);
            
                % grey channel
                greyChannel = app.Outputimagedata(:, :, 1);
                
                % grey histogram
                greyHistogram = zeros(256, 1);

                % Calculate histograms for each channel
                [height, width] = size(greyChannel);
                
                % calculate histogram
                for i = 1:height
                    for j = 1:width
                        greyValue = greyChannel(i, j) + 1; % Add 1 to avoid indexing issues
                        
                        greyHistogram(greyValue) = greyHistogram(greyValue) + 1;
                    end
                end
                
                % Plot histograms
                bar(app.UIAxes4, 0:255, greyHistogram, 'k'); % Grey channel histogram
                hold(app.UIAxes4, 'on');
                
                % Set the limit to the maximum value
                ylim(app.UIAxes4, [0, max(greyHistogram)]); 

                % limit x to 0-255
                xlim(app.UIAxes4, [0, 256]);

                % Set a title for the axis
                title(app.UIAxes4, 'Grayscale Image Histogram');
                hold(app.UIAxes4, 'off');
            end
        end
            

        function setText(app, label)
            % set text label
            app.NoFileSelectedLabel.Text = label;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ApplyButton, ApplyButton_2, 
        % ...and 4 other components
        function apply(app, event)
            % Apply so that output image -> input image
            app.Inputimagedata = app.Outputimagedata;
            
            % set histogram and image output
            numChannels = size(app.Outputimagedata, 3);
            app.setHistogramInput(numChannels);
        end

        % Callback function: ChooseImageButton, OpenMenu
        function chooseFileHandler(app, event)
            [file,path] = uigetfile({'*.jpg;*.png;*.jpeg;*.tif;*.bmp', 'Image Files (*.jpg,*.png,*.jpeg,*.tif,*.bmp)'}, 'Select an image file');
            
            if isequal(file, 0) || isequal(path, 0)
               % Handle the case when the user cancels the file selection
               app.NoFileSelectedLabel.Text = 'No file selected';
               return; % Exit the function
            end
            

            % Create the full file path
            fullFilePath = fullfile(path, file);
            % [ ~ , ~ , ext ] = fileparts( fullFilePath );
  
            % Display the image in the app.SpecImage component
            % Load the BMP image
            bmpImage = imread(fullFilePath);
        
            app.Fullimagename = fullFilePath;
            app.Inputimagedata = bmpImage; 
            app.Outputimagedata = bmpImage;
            % Convert BMP to PNG format
            % You may need to adjust the filename and path as needed
            pngFileName = 'converted_image.png';
            pngFilePath = fullfile(path, pngFileName);
            imwrite(bmpImage(:,:,1), pngFilePath);
            
          
            numChannels = size(bmpImage, 3);

            % set all variable
            app.setText(file)
            app.setHistogramInput(numChannels);
        end

        % Button pushed function: ProcessButton_3
        function processHistogram(app, event)
            numChannels = size(app.Inputimagedata, 3);
            app.setHistogramInput(numChannels);
        end

        % Button pushed function: ProcessButton_4
        function processKonvolusiCitra(app, event)
            % memilih mask yang akan digunakan sesuai input pengguna
            if (app.x7GaussianMaskButton.Value == true)
                mask = [
                    1/140,1/140,2/140,2/140,2/140,1/140,1/140;
                    1/140,2/140,2/140,4/140,2/140,2/140,1/140;
                    2/140,2/140,4/140,8/140,4/140,2/140,2/140;
                    2/140,4/140,8/140,16/140,8/140,4/140,2/140;
                    2/140,2/140,4/140,8/140,4/140,2/140,2/140;
                    1/140,2/140,2/140,4/140,2/140,2/140,1/140;
                    1/140,1/140,2/140,2/140,2/140,1/140,1/140;
                    ];
            elseif (app.x31Button.Value == true)
                mask = [1/16, 2/16, 2/16; 2/16, 4/16, 2/16; 1/16, 2/16, 1/16];
            elseif (app.x32Button.Value == true)
                mask = [0, -1, 0; -1, 4, -1; 0, -1, 0];
            elseif (app.x33Button.Value == true)
                mask = [-1, -1, -1; -1, 17, -1; -1, -1, -1];
            else 
                mask = randi([1, 5], 3, 3);
            end

            % memanggil fungsi konvolusi citra
            app.Outputimagedata = uint8(konvolusiCitra(double(app.Inputimagedata), mask,1,1));

            % set histogram and image output
            numChannels = size(app.Outputimagedata, 3);
            app.setHistogramOutput(numChannels);
        end

        % Button pushed function: AddSaltPaperButton
        function AddSaltPaperButtonPushed(app, event)
            d = app.density.Value;
            % memanggil fungsi untuk menambahkan salt and papper
            app.Outputimagedata = SaltPaper(app.Inputimagedata, d);

            % set histogram and image output
            numChannels = size(app.Outputimagedata, 3);
            app.setHistogramOutput(numChannels);
        end

        % Button pushed function: MinFilterButton
        function MinFilterButtonPushed(app, event)
            % mendapatkan ukuran filter
            ukuranFilterVal = app.ukuranFilter.Value;
            
            % memanggil fungsi min filter
            app.Outputimagedata = minFilter(app.Inputimagedata, ukuranFilterVal);

            % set histogram and image output
            numChannels = size(app.Outputimagedata, 3);
            app.setHistogramOutput(numChannels);
        end

        % Button pushed function: MaxFilterButton
        function MaxFilterButtonPushed(app, event)
            ukuranFilterVal = app.ukuranFilter.Value;
            app.Outputimagedata = maxFilter(app.Inputimagedata, ukuranFilterVal );
            numChannels = size(app.Outputimagedata, 3);
            app.setHistogramOutput(numChannels);
        end

        % Button pushed function: MedianFilterButton
        function MedianFilterButtonPushed(app, event)
            ukuranFilterVal = app.ukuranFilter.Value;
            app.Outputimagedata = medianFilter(app.Inputimagedata, ukuranFilterVal );
            numChannels = size(app.Outputimagedata, 3);
            app.setHistogramOutput(numChannels);
        end

        % Button pushed function: ArithmeticMeanFilterButton
        function ArithmeticMeanFilterButtonPushed(app, event)
            ukuranFilterVal = app.ukuranFilter.Value;
            app.Outputimagedata = arithmeticMeanFilter(app.Inputimagedata, ukuranFilterVal );
            numChannels = size(app.Outputimagedata, 3);
            app.setHistogramOutput(numChannels);
        end

        % Button pushed function: GeometricFilterButton
        function GeometricFilterButtonPushed(app, event)
            ukuranFilterVal = app.ukuranFilter.Value;
            app.Outputimagedata = geometricMeanFilter(app.Inputimagedata, ukuranFilterVal );
            numChannels = size(app.Outputimagedata, 3);
            app.setHistogramOutput(numChannels);
        end

        % Button pushed function: HarmonicMeanFilterButton
        function HarmonicMeanFilterButtonPushed(app, event)
            ukuranFilterVal = app.ukuranFilter.Value;
            app.Outputimagedata = harmonicMeanFilter(app.Inputimagedata, ukuranFilterVal );
            numChannels = size(app.Outputimagedata, 3);
            app.setHistogramOutput(numChannels);
        end

        % Button pushed function: MidpointFilterButton
        function MidpointFilterButtonPushed(app, event)
            ukuranFilterVal = app.ukuranFilter.Value;
            app.Outputimagedata = midpointFilter(app.Inputimagedata, ukuranFilterVal );
            numChannels = size(app.Outputimagedata, 3);
            app.setHistogramOutput(numChannels);
        end

        % Button pushed function: ContraharmonicMeanFilterButton
        function ContraharmonicMeanFilterButtonPushed(app, event)
            Q = app.qVal.Value;
            ukuranFilterVal = app.ukuranFilter.Value;
            app.Outputimagedata = contraharmonicMeanFilter(app.Inputimagedata, ukuranFilterVal, Q);
            numChannels = size(app.Outputimagedata, 3);
            app.setHistogramOutput(numChannels);
        end

        % Button pushed function: AlphatrimmedMeanFilterButton
        function AlphatrimmedMeanFilterButtonPushed(app, event)
            D = app.dVal.Value;
            ukuranFilterVal = app.ukuranFilter.Value;
            app.Outputimagedata = alphaTrimmedMeanFilter(app.Inputimagedata, ukuranFilterVal, D);
            numChannels = size(app.Outputimagedata, 3);
            app.setHistogramOutput(numChannels);
        end

        % Button pushed function: AddMotionBlurButton
        function AddMotionBlurButtonPushed(app, event)
            l = app.len.Value;
            deg = app.degree.Value;
            H = fspecial('motion', l, deg); 
            app.Outputimagedata=imfilter(app.Inputimagedata, H, 'conv', 'circular');
            numChannels = size(app.Outputimagedata, 3);
            app.setHistogramOutput(numChannels);
        end

        % Button pushed function: PenapisWeinerButton
        function PenapisWeinerButtonPushed(app, event)
            l = app.len.Value;
            deg = app.degree.Value;
            noise = app.NoiseVar.Value;
            H = fspecial('motion', l, deg);

            % memanggil fungsi penapis wiener
            app.Outputimagedata = penapisWiener(app.Inputimagedata,H,noise);
            % app.Outputimagedata = deconvwnr(app.Inputimagedata, H, noise);
            % set histogram and image output
            numChannels = size(app.Outputimagedata, 3);
            app.setHistogramOutput(numChannels);
        end

        % Button pushed function: ProcessButton_5
        function ProcessSmoothing(app, event)
            numChannels = size(app.Inputimagedata, 3);
            switch app.SmoothingMethods.SelectedObject
                case app.MeanfilterButton
                    app.Outputimagedata = spatialMeanFilter(app.Inputimagedata, round(app.SmoothingMaskSize.Value));
                    app.setHistogramOutput(numChannels);
                case app.GaussianfilterButton
                    app.Outputimagedata = spatialGaussFilter(app.Inputimagedata, round(app.SmoothingMaskSize.Value));
                    app.setHistogramOutput(numChannels);
                case app.ILPFButton
                    app.Outputimagedata = ILPFilter(app.Inputimagedata, round(app.SmoothingD0.Value));
                    app.setHistogramOutput(numChannels);
                case app.GLPFButton
                    app.Outputimagedata = GLPFilter(app.Inputimagedata, round(app.SmoothingD0.Value));
                    app.setHistogramOutput(numChannels);
                case app.BLPFButton
                    app.Outputimagedata = BLPFilter(app.Inputimagedata, round(app.SmoothingD0.Value));
                    app.setHistogramOutput(numChannels);
            end
        end

        % Button pushed function: ProcessButton_6
        function ProcessHighpass(app, event)
            numChannels = size(app.Inputimagedata, 3);
            switch app.HighpassMethod.SelectedObject
                case app.IHPFButton
                    app.Outputimagedata = IHPFilter(app.Inputimagedata, round(app.SmoothingD0.Value));
                    app.setHistogramOutput(numChannels);
                case app.GHPFButton
                    app.Outputimagedata = GHPFilter(app.Inputimagedata, round(app.SmoothingD0.Value));
                    app.setHistogramOutput(numChannels);
                case app.BHPFButton
                    app.Outputimagedata = BHPFilter(app.Inputimagedata, round(app.SmoothingD0.Value));
                    app.setHistogramOutput(numChannels);
            end
        end

        % Button pushed function: ProcessButton_7
        function ProcessDenoisePeriodic(app, event)
            app.Outputimagedata = denoisePeriodic(app.Inputimagedata);
            
            numChannels = size(app.Inputimagedata, 3);
            app.setHistogramOutput(numChannels);
        end

        % Button pushed function: ProccessButton
        function ProccessButtonPushed(app, event)
            X = app.NilaiX.Value;
            app.Outputimagedata = citraTerang(app.Inputimagedata, X);
            
            numChannels = size(app.Inputimagedata, 3);
            app.setHistogramOutput(numChannels);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @chooseFileHandler, true);
            app.OpenMenu.Accelerator = 'o';
            app.OpenMenu.Text = 'Open...';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TabGroup.Tooltip = {'Histogram'};
            app.OpenFileTab = uitab(app.TabGroup);
            app.OpenFileTab.Title = 'Open File';
            app.GridLayout6_2 = uigridlayout(app.OpenFileTab);
            app.NoFileSelectedLabel = uilabel(app.GridLayout6_2);
            app.NoFileSelectedLabel.Text = 'No File Selected';
            app.ChooseImageButton = uibutton(app.GridLayout6_2, 'push');
            app.ChooseImageButton.ButtonPushedFcn = createCallbackFcn(app, @chooseFileHandler, true);
            app.ChooseImageButton.Text = 'Choose Image';
            app.HistogramTab = uitab(app.TabGroup);
            app.HistogramTab.Title = 'Histogram';
            app.GridLayout6 = uigridlayout(app.HistogramTab);
            app.ProcessButton_3 = uibutton(app.GridLayout6, 'push');
            app.ProcessButton_3.ButtonPushedFcn = createCallbackFcn(app, @processHistogram, true);
            app.ProcessButton_3.Text = 'Process';
            app.KonvolusiTab = uitab(app.TabGroup);
            app.KonvolusiTab.Title = 'Konvolusi';
            app.GridLayout7 = uigridlayout(app.KonvolusiTab);
            app.ProcessButton_4 = uibutton(app.GridLayout7, 'push');
            app.ProcessButton_4.ButtonPushedFcn = createCallbackFcn(app, @processKonvolusiCitra, true);
            app.ProcessButton_4.Text = 'Process';
            app.ApplyButton = uibutton(app.GridLayout7, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @apply, true);
            app.ApplyButton.Text = 'Apply';
            app.ChooseMaskButtonGroup = uibuttongroup(app.GridLayout7);
            app.ChooseMaskButtonGroup.Title = 'Choose Mask';
            app.x31Button = uiradiobutton(app.ChooseMaskButtonGroup);
            app.x31Button.Text = '3 x 3 (1)';
            app.x31Button.Value = true;
            app.x32Button = uiradiobutton(app.ChooseMaskButtonGroup);
            app.x32Button.Text = '3 x 3 (2)';
            app.x33Button = uiradiobutton(app.ChooseMaskButtonGroup);
            app.x33Button.Text = '3 x 3 (3)';
            app.x7GaussianMaskButton = uiradiobutton(app.ChooseMaskButtonGroup);
            app.x7GaussianMaskButton.Text = '7 x 7 Gaussian Mask';
            app.Image = uiimage(app.GridLayout7);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'AssetGUI', 'Screenshot 2023-09-30 154001.png');
            app.Image2 = uiimage(app.GridLayout7);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'AssetGUI', '2.png');
            app.Image3 = uiimage(app.GridLayout7);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'AssetGUI', '3.png');
            app.Image4 = uiimage(app.GridLayout7);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'AssetGUI', '4.png');
            app.x31Label = uilabel(app.GridLayout7);
            app.x31Label.Text = '3 x 3 (1)';
            app.x32Label = uilabel(app.GridLayout7);
            app.x32Label.Text = '3 x 3 (2)';
            app.x33Label = uilabel(app.GridLayout7);
            app.x33Label.Text = '3 x 3 (3)';
            app.x7GaussianMaskLabel = uilabel(app.GridLayout7);
            app.x7GaussianMaskLabel.Text = '7 x 7 Gaussian Mask';
            app.SmoothingTab = uitab(app.TabGroup);
            app.SmoothingTab.Title = 'Smoothing';
            app.GridLayout7_2 = uigridlayout(app.SmoothingTab);
            app.ProcessButton_5 = uibutton(app.GridLayout7_2, 'push');
            app.ProcessButton_5.ButtonPushedFcn = createCallbackFcn(app, @ProcessSmoothing, true);
            app.ProcessButton_5.Text = 'Process';
            app.ApplyButton_2 = uibutton(app.GridLayout7_2, 'push');
            app.ApplyButton_2.ButtonPushedFcn = createCallbackFcn(app, @apply, true);
            app.ApplyButton_2.Text = 'Apply';
            app.SmoothingMethods = uibuttongroup(app.GridLayout7_2);
            app.SmoothingMethods.Title = 'Method';
            app.MeanfilterButton = uiradiobutton(app.SmoothingMethods);
            app.MeanfilterButton.Text = 'Mean filter';
            app.MeanfilterButton.Value = true;
            app.GaussianfilterButton = uiradiobutton(app.SmoothingMethods);
            app.GaussianfilterButton.Text = 'Gaussian filter';
            app.ILPFButton = uiradiobutton(app.SmoothingMethods);
            app.ILPFButton.Text = 'ILPF';
            app.GLPFButton = uiradiobutton(app.SmoothingMethods);
            app.GLPFButton.Text = 'GLPF';
            app.BLPFButton = uiradiobutton(app.SmoothingMethods);
            app.BLPFButton.Text = 'BLPF';
            app.nmasksizeformeanandgaussianfilteringLabel = uilabel(app.GridLayout7_2);
            app.nmasksizeformeanandgaussianfilteringLabel.Text = {'n (mask size)'; 'for mean and '; 'gaussian filtering'};
            app.SmoothingMaskSize = uieditfield(app.GridLayout7_2, 'numeric');
            app.SmoothingMaskSize.Limits = [1 Inf];
            app.SmoothingMaskSize.RoundFractionalValues = 'on';
            app.SmoothingMaskSize.Value = 3;
            app.D0radiusforILPFGLPFandBLPFEditFieldLabel = uilabel(app.GridLayout7_2);
            app.D0radiusforILPFGLPFandBLPFEditFieldLabel.Text = {'D0 (radius)'; 'for ILPF, GLPF, and BLPF'};
            app.SmoothingD0 = uieditfield(app.GridLayout7_2, 'numeric');
            app.SmoothingD0.Limits = [1 Inf];
            app.SmoothingD0.RoundFractionalValues = 'on';
            app.SmoothingD0.Value = 30;
            app.HighpassTab = uitab(app.TabGroup);
            app.HighpassTab.Title = 'High-pass';
            app.GridLayout7_3 = uigridlayout(app.HighpassTab);
            app.ProcessButton_6 = uibutton(app.GridLayout7_3, 'push');
            app.ProcessButton_6.ButtonPushedFcn = createCallbackFcn(app, @ProcessHighpass, true);
            app.ProcessButton_6.Text = 'Process';
            app.ApplyButton_3 = uibutton(app.GridLayout7_3, 'push');
            app.ApplyButton_3.ButtonPushedFcn = createCallbackFcn(app, @apply, true);
            app.ApplyButton_3.Text = 'Apply';
            app.HighpassMethod = uibuttongroup(app.GridLayout7_3);
            app.HighpassMethod.Title = 'Method';
            app.IHPFButton = uiradiobutton(app.HighpassMethod);
            app.IHPFButton.Text = 'IHPF';
            app.IHPFButton.Value = true;
            app.GHPFButton = uiradiobutton(app.HighpassMethod);
            app.GHPFButton.Text = 'GHPF';
            app.BHPFButton = uiradiobutton(app.HighpassMethod);
            app.BHPFButton.Text = 'BHPF';
            app.D0Label = uilabel(app.GridLayout7_3);
            app.D0Label.Text = 'D0';
            app.HighpassD0 = uieditfield(app.GridLayout7_3, 'numeric');
            app.HighpassD0.Limits = [1 Inf];
            app.HighpassD0.RoundFractionalValues = 'on';
            app.HighpassD0.Value = 30;
            app.DerauTab = uitab(app.TabGroup);
            app.DerauTab.Title = 'Derau';
            app.GridLayout8 = uigridlayout(app.DerauTab);
            app.AddSaltPaperButton = uibutton(app.GridLayout8, 'push');
            app.AddSaltPaperButton.ButtonPushedFcn = createCallbackFcn(app, @AddSaltPaperButtonPushed, true);
            app.AddSaltPaperButton.Text = 'Add Salt & Paper';
            app.MinFilterButton = uibutton(app.GridLayout8, 'push');
            app.MinFilterButton.ButtonPushedFcn = createCallbackFcn(app, @MinFilterButtonPushed, true);
            app.MinFilterButton.Text = 'Min Filter';
            app.MaxFilterButton = uibutton(app.GridLayout8, 'push');
            app.MaxFilterButton.ButtonPushedFcn = createCallbackFcn(app, @MaxFilterButtonPushed, true);
            app.MaxFilterButton.Text = 'Max Filter';
            app.MedianFilterButton = uibutton(app.GridLayout8, 'push');
            app.MedianFilterButton.ButtonPushedFcn = createCallbackFcn(app, @MedianFilterButtonPushed, true);
            app.MedianFilterButton.Text = 'Median Filter';
            app.ArithmeticMeanFilterButton = uibutton(app.GridLayout8, 'push');
            app.ArithmeticMeanFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ArithmeticMeanFilterButtonPushed, true);
            app.ArithmeticMeanFilterButton.Text = 'Arithmetic Mean Filter';
            app.GeometricFilterButton = uibutton(app.GridLayout8, 'push');
            app.GeometricFilterButton.ButtonPushedFcn = createCallbackFcn(app, @GeometricFilterButtonPushed, true);
            app.GeometricFilterButton.Text = 'Geometric Filter';
            app.HarmonicMeanFilterButton = uibutton(app.GridLayout8, 'push');
            app.HarmonicMeanFilterButton.ButtonPushedFcn = createCallbackFcn(app, @HarmonicMeanFilterButtonPushed, true);
            app.HarmonicMeanFilterButton.Text = 'Harmonic Mean Filter';
            app.ContraharmonicMeanFilterButton = uibutton(app.GridLayout8, 'push');
            app.ContraharmonicMeanFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ContraharmonicMeanFilterButtonPushed, true);
            app.ContraharmonicMeanFilterButton.Text = {'Contraharmonic '; 'Mean Filter'};
            app.MidpointFilterButton = uibutton(app.GridLayout8, 'push');
            app.MidpointFilterButton.ButtonPushedFcn = createCallbackFcn(app, @MidpointFilterButtonPushed, true);
            app.MidpointFilterButton.Text = 'Midpoint Filter';
            app.AlphatrimmedMeanFilterButton = uibutton(app.GridLayout8, 'push');
            app.AlphatrimmedMeanFilterButton.ButtonPushedFcn = createCallbackFcn(app, @AlphatrimmedMeanFilterButtonPushed, true);
            app.AlphatrimmedMeanFilterButton.Text = {'Alpha-trimmed'; 'Mean Filter'};
            app.GridLayout9 = uigridlayout(app.GridLayout8);
            app.densityEditFieldLabel = uilabel(app.GridLayout9);
            app.densityEditFieldLabel.Text = 'density';
            app.density = uieditfield(app.GridLayout9, 'numeric');
            app.density.Limits = [0 1];
            app.ApplySaltButton = uibutton(app.GridLayout8, 'push');
            app.ApplySaltButton.ButtonPushedFcn = createCallbackFcn(app, @apply, true);
            app.ApplySaltButton.Text = 'Apply Salt';
            app.GridLayout10 = uigridlayout(app.GridLayout8);
            app.filterNxNLabel = uilabel(app.GridLayout10);
            app.filterNxNLabel.Text = 'filter (NxN)';
            app.ukuranFilter = uieditfield(app.GridLayout10, 'numeric');
            app.GridLayout11 = uigridlayout(app.GridLayout8);
            app.QEditFieldLabel = uilabel(app.GridLayout11);
            app.QEditFieldLabel.Text = 'Q';
            app.qVal = uieditfield(app.GridLayout11, 'numeric');
            app.GridLayout12 = uigridlayout(app.GridLayout8);
            app.alphaLabel = uilabel(app.GridLayout12);
            app.alphaLabel.Text = 'alpha';
            app.dVal = uieditfield(app.GridLayout12, 'numeric');
            app.PeriodicTab = uitab(app.TabGroup);
            app.PeriodicTab.Title = 'Periodic';
            app.GridLayout18 = uigridlayout(app.PeriodicTab);
            app.ProcessButton_7 = uibutton(app.GridLayout18, 'push');
            app.ProcessButton_7.ButtonPushedFcn = createCallbackFcn(app, @ProcessDenoisePeriodic, true);
            app.ProcessButton_7.Text = 'Process';
            app.ApplyButton_4 = uibutton(app.GridLayout18, 'push');
            app.ApplyButton_4.ButtonPushedFcn = createCallbackFcn(app, @apply, true);
            app.ApplyButton_4.Text = 'Apply';
            app.AutomaticallydenoiseperiodicnoiseLabel = uilabel(app.GridLayout18);
            app.AutomaticallydenoiseperiodicnoiseLabel.Text = 'Automatically denoise periodic noise';
            app.NoisyimagesamplesareavailableinthetestfolderLabel = uilabel(app.GridLayout18);
            app.NoisyimagesamplesareavailableinthetestfolderLabel.Text = 'Noisy image samples are available in the test folder';
            app.MotionblurTab = uitab(app.TabGroup);
            app.MotionblurTab.Title = 'Motion blur';
            app.GridLayout13 = uigridlayout(app.MotionblurTab);
            app.AddMotionBlurButton = uibutton(app.GridLayout13, 'push');
            app.AddMotionBlurButton.ButtonPushedFcn = createCallbackFcn(app, @AddMotionBlurButtonPushed, true);
            app.AddMotionBlurButton.Text = 'Add Motion Blur';
            app.GridLayout14 = uigridlayout(app.GridLayout13);
            app.lenEditFieldLabel = uilabel(app.GridLayout14);
            app.lenEditFieldLabel.Text = 'len';
            app.len = uieditfield(app.GridLayout14, 'numeric');
            app.GridLayout15 = uigridlayout(app.GridLayout13);
            app.degreeEditFieldLabel = uilabel(app.GridLayout15);
            app.degreeEditFieldLabel.Text = 'degree';
            app.degree = uieditfield(app.GridLayout15, 'numeric');
            app.ApplyMotionBlurButton = uibutton(app.GridLayout13, 'push');
            app.ApplyMotionBlurButton.ButtonPushedFcn = createCallbackFcn(app, @apply, true);
            app.ApplyMotionBlurButton.Text = 'Apply Motion Blur';
            app.PenapisWeinerButton = uibutton(app.GridLayout13, 'push');
            app.PenapisWeinerButton.ButtonPushedFcn = createCallbackFcn(app, @PenapisWeinerButtonPushed, true);
            app.PenapisWeinerButton.Text = 'Penapis Weiner';
            app.GridLayout16 = uigridlayout(app.GridLayout13);
            app.NoiseVarEditFieldLabel = uilabel(app.GridLayout16);
            app.NoiseVarEditFieldLabel.Text = 'Noise Var';
            app.NoiseVar = uieditfield(app.GridLayout16, 'numeric');
            app.misal0001Label = uilabel(app.GridLayout13);
            app.misal0001Label.Text = 'misal 0.001';
            app.CitraTerangTab = uitab(app.TabGroup);
            app.CitraTerangTab.Title = 'Citra Terang';
            app.GridLayout19 = uigridlayout(app.CitraTerangTab);
            app.ProccessButton = uibutton(app.GridLayout19, 'push');
            app.ProccessButton.ButtonPushedFcn = createCallbackFcn(app, @ProccessButtonPushed, true);
            app.ProccessButton.Text = 'Proccess';
            app.NilaiXEditFieldLabel = uilabel(app.GridLayout19);
            app.NilaiXEditFieldLabel.Text = 'Nilai X';
            app.NilaiX = uieditfield(app.GridLayout19, 'numeric');
            app.TabGroup2 = uitabgroup(app.GridLayout);
            app.ImageTab = uitab(app.TabGroup2);
            app.ImageTab.Title = 'Image';
            app.GridLayout5 = uigridlayout(app.ImageTab);
            app.OutputImage = uiimage(app.GridLayout5);
            app.InputImage = uiimage(app.GridLayout5);
            app.SpectrumTab = uitab(app.TabGroup2);
            app.SpectrumTab.Title = 'Spectrum';
            app.GridLayout17 = uigridlayout(app.SpectrumTab);
            app.InputSpectrum = uiimage(app.GridLayout17);
            app.InputLabel_2 = uilabel(app.GridLayout17);
            app.InputLabel_2.Text = 'Input';
            app.OutputLabel_2 = uilabel(app.GridLayout17);
            app.OutputLabel_2.Text = 'Output';
            app.OutputSpectrum = uiimage(app.GridLayout17);
            app.HistogramTab_2 = uitab(app.TabGroup2);
            app.HistogramTab_2.Title = 'Histogram';
            app.GridLayout4 = uigridlayout(app.HistogramTab_2);
            app.UIAxes = uiaxes(app.GridLayout4);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout4);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.GridLayout4);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes6 = uiaxes(app.GridLayout4);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes5 = uiaxes(app.GridLayout4);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes4 = uiaxes(app.GridLayout4);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.InputLabel = uilabel(app.GridLayout4);
            app.InputLabel.Text = 'Input';
            app.OutputLabel = uilabel(app.GridLayout4);
            app.OutputLabel.Text = 'Output';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = adohe_fototoko_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end